classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GraphButton           matlab.ui.control.Button
        TestingifthecodeworksandisinteractableLabel  matlab.ui.control.Label
        ArcLengthSlider       matlab.ui.control.Slider
        ArcLengthSliderLabel  matlab.ui.control.Label
        RadiusSlider          matlab.ui.control.Slider
        RadiusSliderLabel     matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RadiusSlider
        function RadiusSliderValueChanged(app, event)
            r = app.RadiusSlider.Value;
            L = 50;
            x = [0 0 0 0 0 0];y = [-5 -4 -3 -2 -1 0];
            X = 0.01;
            increment = 6;
            while ~(y(increment)>= r*sin(L/r)) || ~(X>=r-r*cos(L/r))
                x = [x, X];
                y = [y, sqrt(2*r*X-X^2)];
                increment = increment + 1;
                X = X + 0.01;
            end
            
            p = plot(app.UIAxes,x,y,x,y);

            p(1).LineWidth = 10;
            p(1).Color = 'black';
            p(2).LineStyle = "--";
            p(2).Color = 'white';
            p(2).LineWidth = 1;
            xlim([-5 50]);
            ylim([-5 50]);
            %             x = [0 0 0 0 0 0];y = [-5 -4 -3 -2 -1 0];
%             X = 0.1;
%             increment = 6;
%             while ~(y(increment)>= r*sin(L/r)) || ~(X>=r-r*cos(L/r))
%                 x = [x, X];
%                 y = [y, sqrt(L*X-X^2)];
%                 increment = increment + 1;
%                 X = X + 0.1;
%             end
% 
% 
%             p = plot(app.UIAxes,x,y,x,y);
% 
%             p(1).LineWidth = 10;
%             p(1).Color = 'black';
%             p(2).LineStyle = "--";
%             p(2).Color = 'white';
%             p(2).LineWidth = 1;
%             app.UIAxis.xlim([-1 50]);
%             app.UIAxis.ylim([-1 50]);            
        end

        % Value changed function: ArcLengthSlider
        function ArcLengthSliderValueChanged(app, event)
%             r = app.ArcLengthSlider.Value;
%             L = app.RadiusSlider.Value;
%             x = [0 0 0 0 0 0];y = [-5 -4 -3 -2 -1 0];
%             X = 0.1;
%             increment = 6;
%             while y(increment)<= r*sin(L/r) || x(increment)<=r-r*cos(L/r)
%                 x = [x, X];
%                 y = [y, sqrt(L*X-X^2)];
%                 increment = increment + 1;
%                 X = X + 0.1;
%             end
% 
% 
%             p = plot(app.UIAxes,x,y,x,y);
% 
%             p(1).LineWidth = 10;
%             p(1).Color = 'black';
%             p(2).LineStyle = "--";
%             p(2).Color = 'white';
%             p(2).LineWidth = 1;
%             app.UIAxis.xlim([-1 50]);
%             app.UIAxis.ylim([-1 50]);
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            r = app.ArcLengthSlider.Value;
            GraphOfLengthCode(r,50)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-2 50];
            app.UIAxes.YLim = [-5 50];
            app.RadiusSliderLabel = uilabel(app.UIFigure);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.UIFigure);
            app.RadiusSlider.Limits = [16 200];
            app.RadiusSlider.ValueChangedFcn = createCallbackFcn(app, @RadiusSliderValueChanged, true);
            app.RadiusSlider.Value = 16;
            app.ArcLengthSliderLabel = uilabel(app.UIFigure);
            app.ArcLengthSliderLabel.Text = 'Arc Length';
            app.ArcLengthSlider = uislider(app.UIFigure);
            app.ArcLengthSlider.ValueChangedFcn = createCallbackFcn(app, @ArcLengthSliderValueChanged, true);
            app.TestingifthecodeworksandisinteractableLabel = uilabel(app.UIFigure);
            app.TestingifthecodeworksandisinteractableLabel.Text = 'Testing if the code works and is interactable';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end