classdef app_muaadh < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Button_9                    matlab.ui.control.Button
        TyreTypesDropDown           matlab.ui.control.DropDown
        TyreTypesDropDownLabel      matlab.ui.control.Label
        MoreInfoaboutProgramButton  matlab.ui.control.Button
        Button_8                    matlab.ui.control.Button
        Button_7                    matlab.ui.control.Button
        Button_6                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        MasskgEditField             matlab.ui.control.NumericEditField
        MasskgEditFieldLabel        matlab.ui.control.Label
        MaximumBankAngleEditField   matlab.ui.control.NumericEditField
        MaximumBankAngleLabel       matlab.ui.control.Label
        VelocitymsEditField         matlab.ui.control.NumericEditField
        VelocitymsEditFieldLabel    matlab.ui.control.Label
        AnimateButton               matlab.ui.control.Button
        GForceandFrictioncalculatoronbankedroadsLabel  matlab.ui.control.Label
        RadiusSlider                matlab.ui.control.Slider
        RadiusSliderLabel           matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RadiusSlider
        function RadiusSliderValueChanged(app, event)
            global x y;%using global here to allow us to access the x,y later on when the button is pushed
            r = app.RadiusSlider.Value;%takes the radius value
            L = 50;%our fixed arc length
            x = zeros(1,100);y = linspace(-10,0); %additional padding to the beginning of the graph to have a "straight road"
            X = 0.01;
            increment = length(x);
            while ~(y(increment)>= r*sin(L/r)) || ~(X>=r-r*cos(L/r))
                x = [x, X];
                y = [y, sqrt(2*r*X-X^2)];
                increment = increment + 1;
                X = X + 0.01;
            end
            %the while loop section above basically fills in the x/y
            %vectors with the values for the graph of that curve/road
            p = plot(app.UIAxes,x,y,x,y);

            p(1).LineWidth = 10;
            p(1).Color = 'black';
            p(2).LineStyle = "--";
            p(2).Color = 'white';
            p(2).LineWidth = 1;
            %stuff added by Muaadh to create the actual road graph
        end

        % Callback function
        function ArcLengthSliderValueChanged(app, event)

        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            global x y;
            p = plot(app.UIAxes,x,y,x,y);
            p(1).LineWidth = 10;
            p(1).Color = 'black';
            p(2).LineStyle = "--";
            p(2).Color = 'white';
            p(2).LineWidth = 1;
            hold(app.UIAxes);
            p = plot(app.UIAxes,x(1),y(1),'o','MarkerFaceColor','red');
            hold(app.UIAxes);

            %axis manual

            v = app.VelocitymsEditField.Value;
            bankAng = app.MaximumBankAngleEditField.Value;
            m=app.MasskgEditField.Value;
            r = app.RadiusSlider.Value;
            G = [];
            F = [];
            
            if bankAng > 0
                

                for i = 0:(bankAng/10):bankAng
    
                    [g,f]=Gforcefriction(v,r,m,i,0.7);
    
                    G=[G,g];
                    F=[F,f];
    
                end
                i = 0:(bankAng/10):bankAng;
                plot(app.UIAxes3,i,G)
                plot(app.UIAxes2,i,F)

            elseif bankAng == 0
                [g,f]=Gforcefriction(v,r,m,0,0.7);
                G = ones(1,11)*g;
                F = ones(1,11)*f;
                plot(app.UIAxes3,1:11,G)
                plot(app.UIAxes2,1:11,F)
                xlabel(app.UIAxes2, 'Distance travelled on the road')
                xlabel(app.UIAxes3, 'Distance travelled on the road')
            end
            

            for k = 2:15:length(x)
                p.XData = x(k);
                p.YData = y(k);
                drawnow
            end
        end

        % Button pushed function: MoreInfoaboutProgramButton
        function MoreInfoaboutProgramButtonPushed(app, event)
            %TODO: ADD info, credits, and BS
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %TODO: dialog box explaining Radius of curvature
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %TODO: dialog box explaining Velocity
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %TODO: dialog box explaining Mass
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %TODO: dialog box explaining Bank Angle
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %TODO: dialog box explaining Animate button use
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %TODO: dialog box explaining graph of road and dot
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            %TODO: dialog box explaining Friction graph
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            %TODO: dialog box explaining G force graph
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            %TODO: dialog box explaining Tyre types
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Representation of the vehicle')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-2 50];
            app.UIAxes.YLim = [-5 50];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Friction')
            xlabel(app.UIAxes2, 'Bank Angle')
            ylabel(app.UIAxes2, 'Friction')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'G Force')
            xlabel(app.UIAxes3, 'Bank Angle')
            ylabel(app.UIAxes3, 'G-Force')
            zlabel(app.UIAxes3, 'Z')
            app.RadiusSliderLabel = uilabel(app.UIFigure);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.UIFigure);
            app.RadiusSlider.Limits = [16 200];
            app.RadiusSlider.ValueChangedFcn = createCallbackFcn(app, @RadiusSliderValueChanged, true);
            app.RadiusSlider.Value = 16;
            app.GForceandFrictioncalculatoronbankedroadsLabel = uilabel(app.UIFigure);
            app.GForceandFrictioncalculatoronbankedroadsLabel.Text = 'G-Force and Friction calculator on banked roads';
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.VelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.VelocitymsEditFieldLabel.Text = 'Velocity (m/s)';
            app.VelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocitymsEditField.Value = 5;
            app.MaximumBankAngleLabel = uilabel(app.UIFigure);
            app.MaximumBankAngleLabel.Text = 'Maximum Bank Angle ( ° )';
            app.MaximumBankAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditField.Value = 900;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '?';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '?';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '?';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '?';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '?';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '?';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '?';
            app.MoreInfoaboutProgramButton = uibutton(app.UIFigure, 'push');
            app.MoreInfoaboutProgramButton.ButtonPushedFcn = createCallbackFcn(app, @MoreInfoaboutProgramButtonPushed, true);
            app.MoreInfoaboutProgramButton.Text = 'More Info about Program';
            app.TyreTypesDropDownLabel = uilabel(app.UIFigure);
            app.TyreTypesDropDownLabel.Text = 'Tyre Types';
            app.TyreTypesDropDown = uidropdown(app.UIFigure);
            app.TyreTypesDropDown.Items = {'Soft', 'Racing'};
            app.TyreTypesDropDown.Value = 'Soft';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_muaadh

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end